classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TimeShiftSliderLabel   matlab.ui.control.Label
        TimeShiftSlider        matlab.ui.control.Slider
        onsetSlider            matlab.ui.control.Slider
        LockyscaleCheckBox     matlab.ui.control.CheckBox
        SaveButton             matlab.ui.control.Button
        onsetLabel             matlab.ui.control.Label
        RstartSlider           matlab.ui.control.Slider
        RendSlider             matlab.ui.control.Slider
        ClearButton            matlab.ui.control.Button
        statBox                matlab.ui.control.Label
        n0SliderLabel          matlab.ui.control.Label
        n0Slider               matlab.ui.control.Slider
        LoadDataDropDownLabel  matlab.ui.control.Label
        LoadDataDropDown       matlab.ui.control.DropDown
        DeathsButton           matlab.ui.control.Button
        casesPlot              matlab.ui.control.UIAxes
        RePlot                 matlab.ui.control.UIAxes
        deathsPlot             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        timeShift = 0 % Description
        tau % Description
        Rstart % Description
        onset % Description
        slope % Description
        Ndays % Description
        savedModels % Description
        nCases % Description
        Re % Description
        nDay
        % Description
        n0 % Description
        nDeaths % Description
        Rend % Description
    end
    
    methods (Access = private)
        function results = generateData(app)
%             N = 150;
%             tau = 5;
            app.nDay = (1:app.Ndays);
            [app.nCases, app.Re] = model(app.nDay, app.n0, app.tau, app.Rstart, app.Rend, app.onset, app.slope);
            
            plot(app.casesPlot, app.nDay, app.nCases, '.-b');
            hold(app.casesPlot,'on');
            for i = 1:length(app.savedModels) 
                data = app.savedModels{i};
%                 data(1,:)
                plot(app.casesPlot, data(2,:), data(1,:), '.-k');
            end
            hold(app.casesPlot,'off');

            plot(app.RePlot, app.nDay, app.Re, '.-r');
            
        end
        
        function results = simulateDeaths(app)
            deathDays = [];
            for nd = app.nDay
                nDeaths = poissrnd(0.01*app.nCases(nd));
%                 deathDays = [deathDays, floor(10*randn(1,nDeaths) + nd + 20)];
                deathDays = [deathDays, nd+poissrnd(20,1,nDeaths)];
%                 disp(poissrnd(app.nCases(nd),1,1))
            end
            app.nDeaths = hist(deathDays,app.nDay);
            plot(app.deathsPlot, app.nDay, movmean(app.nDeaths,7), '.-k');            
        end
    end 
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.UITable.RowName = {'Country A'; 'Country B'};
%             app.UITable.Data = [{1 3};{3 3}];
            app.Ndays = 150;
            app.tau = 5;
            app.Rstart = 3;
            app.Rend = 0.7;
            app.onset = 20;
            app.slope = 15;
            app.n0 = 1;
            app.savedModels = {};
            app.generateData();
            
            app.casesPlot.PositionConstraint = 'innerposition';
            app.RePlot.PositionConstraint = 'innerposition';
            app.deathsPlot.PositionConstraint = 'innerposition';
            app.RePlot.InnerPosition(1) = app.casesPlot.InnerPosition(1);
            app.RePlot.InnerPosition(3) = app.casesPlot.InnerPosition(3);
            app.deathsPlot.InnerPosition(1) = app.casesPlot.InnerPosition(1);
            app.deathsPlot.InnerPosition(3) = app.casesPlot.InnerPosition(3);

            app.onsetSlider.Position(1) = app.casesPlot.InnerPosition(1);
            app.onsetSlider.Position(3) = app.casesPlot.InnerPosition(3);

            app.RstartSlider.Position(2) = app.RePlot.InnerPosition(2);
            app.RstartSlider.Position(4) = app.RePlot.InnerPosition(4);
            app.RendSlider.Position(2) = app.RePlot.InnerPosition(2);
            app.RendSlider.Position(4) = app.RePlot.InnerPosition(4);
            
            
        end

        % Value changing function: TimeShiftSlider
        function TimeShiftSliderValueChanging(app, event)
            changingValue = event.Value;
            app.timeShift = changingValue;
            app.generateData();
        end

        % Value changing function: onsetSlider
        function onsetSliderValueChanging(app, event)
%             changingValue = event.Value;
            app.onset = floor(event.Value);
            app.onsetSlider.Value = app.onset;
            set(app.onsetLabel, 'Text', num2str(app.onset));
            app.generateData();
        end

        % Value changed function: LockyscaleCheckBox
        function LockyscaleCheckBoxValueChanged(app, event)
            value = app.LockyscaleCheckBox.Value;
            disp(value)
            if (value) 
                set(app.casesPlot,'YLimMode','manual');
            else
                set(app.casesPlot,'YLimMode','auto');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.savedModels = [app.savedModels, [app.nCases; app.nDay]];
            app.generateData();
        end

        % Value changing function: RstartSlider
        function RstartSliderValueChanging(app, event)
            app.Rstart = event.Value;
            app.generateData();
        end

        % Value changing function: RendSlider
        function RendSliderValueChanging(app, event)
            app.Rend = event.Value;
            app.generateData();
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.savedModels = {};
            app.generateData();
        end

        % Value changing function: n0Slider
        function n0SliderValueChanging(app, event)
            app.n0 = event.Value;
            app.generateData();
        end

        % Button pushed function: DeathsButton
        function DeathsButtonPushed(app, event)
            app.simulateDeaths()
        end

        % Value changed function: onsetSlider
        function onsetSliderValueChanged(app, event)
            app.simulateDeaths()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TimeShiftSliderLabel = uilabel(app.UIFigure);
            app.TimeShiftSliderLabel.Text = 'Time Shift';
            app.TimeShiftSlider = uislider(app.UIFigure);
            app.TimeShiftSlider.Limits = [0 1];
            app.TimeShiftSlider.MajorTicks = [0 1];
            app.TimeShiftSlider.MajorTickLabels = {'0', '1'};
            app.TimeShiftSlider.ValueChangingFcn = createCallbackFcn(app, @TimeShiftSliderValueChanging, true);
            app.onsetSlider = uislider(app.UIFigure);
            app.onsetSlider.Limits = [0 150];
            app.onsetSlider.MajorTicks = [];
            app.onsetSlider.MajorTickLabels = {''};
            app.onsetSlider.ValueChangedFcn = createCallbackFcn(app, @onsetSliderValueChanged, true);
            app.onsetSlider.ValueChangingFcn = createCallbackFcn(app, @onsetSliderValueChanging, true);
            app.onsetSlider.MinorTicks = [];
            app.onsetSlider.Value = 20;
            app.LockyscaleCheckBox = uicheckbox(app.UIFigure);
            app.LockyscaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @LockyscaleCheckBoxValueChanged, true);
            app.LockyscaleCheckBox.Text = 'Lock y-scale';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.onsetLabel = uilabel(app.UIFigure);
            app.onsetLabel.Text = '20';
            app.RstartSlider = uislider(app.UIFigure);
            app.RstartSlider.Limits = [0 3];
            app.RstartSlider.MajorTicks = [];
            app.RstartSlider.ValueChangingFcn = createCallbackFcn(app, @RstartSliderValueChanging, true);
            app.RstartSlider.MinorTicks = [];
            app.RstartSlider.Value = 3;
            app.RendSlider = uislider(app.UIFigure);
            app.RendSlider.Limits = [0 3];
            app.RendSlider.MajorTicks = [];
            app.RendSlider.ValueChangingFcn = createCallbackFcn(app, @RendSliderValueChanging, true);
            app.RendSlider.MinorTicks = [];
            app.RendSlider.Value = 0.7;
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.statBox = uilabel(app.UIFigure);
            app.statBox.Text = 'Infected:';
            app.n0SliderLabel = uilabel(app.UIFigure);
            app.n0SliderLabel.Text = 'n0';
            app.n0Slider = uislider(app.UIFigure);
            app.n0Slider.Limits = [1 101];
            app.n0Slider.MajorTicks = [1 11 21 31 41 51 61 71 81 91 101];
            app.n0Slider.MajorTickLabels = {'1', '', '', '', '', '', '', '', '', '', '100'};
            app.n0Slider.ValueChangingFcn = createCallbackFcn(app, @n0SliderValueChanging, true);
            app.n0Slider.MinorTicks = [];
            app.n0Slider.Value = 1;
            app.LoadDataDropDownLabel = uilabel(app.UIFigure);
            app.LoadDataDropDownLabel.Text = 'Load Data';
            app.LoadDataDropDown = uidropdown(app.UIFigure);
            app.LoadDataDropDown.Items = {'Sweden', 'Denmark'};
            app.LoadDataDropDown.Value = 'Sweden';
            app.DeathsButton = uibutton(app.UIFigure, 'push');
            app.DeathsButton.ButtonPushedFcn = createCallbackFcn(app, @DeathsButtonPushed, true);
            app.DeathsButton.Text = 'Deaths';
            app.casesPlot = uiaxes(app.UIFigure);
            ylabel(app.casesPlot, 'new infected')
            zlabel(app.casesPlot, 'Z')
            app.RePlot = uiaxes(app.UIFigure);
            ylabel(app.RePlot, 'Re')
            zlabel(app.RePlot, 'Z')
            app.RePlot.XLim = [0 150];
            app.RePlot.YLim = [0 3];
            app.RePlot.XTick = [];
            app.RePlot.YTick = [1 2 3];
            app.deathsPlot = uiaxes(app.UIFigure);
            xlabel(app.deathsPlot, 'days')
            ylabel(app.deathsPlot, '# deaths')
            zlabel(app.deathsPlot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end